classdef RC_Car_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        StopButton                matlab.ui.control.Button
        SelectButton              matlab.ui.control.Button
        SerialPortsDropDown       matlab.ui.control.DropDown
        SerialPortsDropDownLabel  matlab.ui.control.Label
        ScanButton                matlab.ui.control.Button
        MoveRightButton           matlab.ui.control.Button
        MoveLeftButton            matlab.ui.control.Button
        MoveDownButton            matlab.ui.control.Button
        MoveUpButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        selected_serial_port % Description
        is_connected % Description
    end
    
    properties (Access = public)
        serial_port_object % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
            app.SerialPortsDropDown.Items = serialportlist("available");
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            app.selected_serial_port = app.SerialPortsDropDown.Value;
            app.serial_port_object = serialport(app.selected_serial_port, 9600);
            app.is_connected = true;
            disp("App: " + app.selected_serial_port)
        end

        % Button pushed function: MoveUpButton
        function MoveUpButtonPushed(app, event)
            if app.is_connected == true
                writeline(app.serial_port_object, '1')
            end
        end

        % Button pushed function: MoveDownButton
        function MoveDownButtonPushed(app, event)
              if app.is_connected == true
                writeline(app.serial_port_object, '2')
              end
        end

        % Button pushed function: MoveLeftButton
        function MoveLeftButtonPushed(app, event)
            if app.is_connected == true
                writeline(app.serial_port_object, '3')
            end
        end

        % Button pushed function: MoveRightButton
        function MoveRightButtonPushed(app, event)
            if app.is_connected == true
                writeline(app.serial_port_object, '4')
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if app.is_connected == true
                writeline(app.serial_port_object, '0')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MoveUpButton = uibutton(app.UIFigure, 'push');
            app.MoveUpButton.ButtonPushedFcn = createCallbackFcn(app, @MoveUpButtonPushed, true);
            app.MoveUpButton.Text = 'Move Up';
            app.MoveDownButton = uibutton(app.UIFigure, 'push');
            app.MoveDownButton.ButtonPushedFcn = createCallbackFcn(app, @MoveDownButtonPushed, true);
            app.MoveDownButton.Text = 'Move Down';
            app.MoveLeftButton = uibutton(app.UIFigure, 'push');
            app.MoveLeftButton.ButtonPushedFcn = createCallbackFcn(app, @MoveLeftButtonPushed, true);
            app.MoveLeftButton.Text = 'Move Left';
            app.MoveRightButton = uibutton(app.UIFigure, 'push');
            app.MoveRightButton.ButtonPushedFcn = createCallbackFcn(app, @MoveRightButtonPushed, true);
            app.MoveRightButton.Text = 'Move Right';
            app.ScanButton = uibutton(app.UIFigure, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Text = 'Scan';
            app.SerialPortsDropDownLabel = uilabel(app.UIFigure);
            app.SerialPortsDropDownLabel.Text = 'Serial Ports';
            app.SerialPortsDropDown = uidropdown(app.UIFigure);
            app.SerialPortsDropDown.Items = {};
            app.SerialPortsDropDown.Value = {};
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RC_Car_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end